classdef Eyenamicfix < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        XValuesLabel      matlab.ui.control.Label
        SwitchButton      matlab.ui.control.StateButton
        Panel             matlab.ui.container.Panel
        ALGO1Button       matlab.ui.control.Button
        Button_4          matlab.ui.control.Button
        Button_3          matlab.ui.control.Button
        TabGroup3         matlab.ui.container.TabGroup
        LeftTab_3         matlab.ui.container.Tab
        GridLayout4       matlab.ui.container.GridLayout
        UIAxes2           matlab.ui.control.UIAxes
        RightTab_3        matlab.ui.container.Tab
        UIAxes2_2         matlab.ui.control.UIAxes
        TabGroup2         matlab.ui.container.TabGroup
        LeftTab_2         matlab.ui.container.Tab
        GridLayout        matlab.ui.container.GridLayout
        LeftLine          matlab.ui.control.UIAxes
        RightTab_2        matlab.ui.container.Tab
        GridLayout2       matlab.ui.container.GridLayout
        RightLine         matlab.ui.control.UIAxes
        TabGroup          matlab.ui.container.TabGroup
        LeftTab           matlab.ui.container.Tab
        Leye              matlab.ui.control.UIAxes
        RightTab          matlab.ui.container.Tab
        Reye              matlab.ui.control.UIAxes
        UITable           matlab.ui.control.Table
        Button_2          matlab.ui.control.Button
        Button            matlab.ui.control.Button
        FileOptionsPanel  matlab.ui.container.Panel
        FileLoad          matlab.ui.control.Button
    end

    
    properties (Access = private)
        GazeFILE % Description
        GazeLOC % Description
        eyeDATA % Description
        CurrentTrial
        LeftEyeSac
        RightEyeSac
        timeDATA
        ALGO1tab % Description
        cmap2use % Description
    end
    
    methods (Access = private)
        
        function loadFIRST(app)

            app.CurrentTrial = 1;
            app.LeftEyeSac.G = app.eyeDATA.L{1};
            app.RightEyeSac.G = app.eyeDATA.R{1};
            app.LeftEyeSac.T = app.timeDATA.L{1};
            app.RightEyeSac.T = app.timeDATA.R{1};

        end
        
        function plotSACCADE(app)


            for lri = 1:2
                % Plot raw Scr points

                if lri == 1
                    gazeRAW = app.LeftEyeSac.G;
                    gazeTIME = app.LeftEyeSac.T;
                    plotID = 'Leye';
                else
                    gazeRAW = app.RightEyeSac.G;
                    gazeTIME = app.RightEyeSac.T;
                    plotID = 'Reye';
                end

                rawS = scatter(app.(plotID),gazeRAW(:,1),gazeRAW(:,2),60,[0.5 0.5 0.5],'filled');
                rawS.MarkerEdgeColor = "none";
                rawS.MarkerFaceAlpha = 0.7;
                % Loop through saccade table

                % PLOT LINE plot use FUNCTION with X as default
             

            end
        end
        
        function [saccades, RMSdist] = detectSaccadesORG(app,eyePositions, samplingRate, params)
            % DETECTSACCADES Detects saccades in eye position data with a loss of eye
            % metric.
            %
            %   Inputs:
            %       eyePositions: 2D array (n x 2) of eye positions (x, y).
            %       samplingRate: Sampling rate of the data (Hz).
            %       params: Structure containing detection parameters:
            %           .velocityThreshold:  Velocity threshold multiplier relative to median velocity
            %           .minDuration:  Minimum saccade duration (ms)
            %           .minAmplitude: Minimum saccade amplitude (pixels)
            %           .lossWindow: Time window in ms for the loss metric calculation.
            %           .lossThreshold: Threshold for the loss metric variance value.
            %
            %   Outputs:
            %       saccades: A matrix where each row is a saccade, with columns:
            %              start index, end index.
            %       isLost: A boolean vector where a 1 indicates a point might be bad data due to loss of the eye.
            % Parameter handling (set defaults if no params passed)
            if nargin < 3 || isempty(params)
                params.velocityThreshold = 4;
                params.minDuration = 20; %ms
                params.minAmplitude = 0.5; %pixels
                params.lossWindow = 100; %ms
                params.lossThreshold = 2; %variance of 2 pixels^2
                warning('Parameter structure not found or empty; setting default saccade detection parameters.');
            end

            % 1. Calculate Velocities
            velocities = calculateVelocity(app,eyePositions, samplingRate);
            speeds = sqrt(sum(velocities.^2, 2)); % Magnitude of the velocities

            % 2. Thresholding
            medianVelocity = median(speeds);
            velocityThreshold = medianVelocity * params.velocityThreshold; % dynamic threshold based on a multiple of the median
            saccadeCandidates = speeds > velocityThreshold;

            % 3. Clustering
            saccades = [];
            inSaccade = false;
            saccadeStart = 0;

            for i = 1:length(saccadeCandidates)
                if saccadeCandidates(i) && ~inSaccade % Saccade start
                    inSaccade = true;
                    saccadeStart = i;
                elseif ~saccadeCandidates(i) && inSaccade % Saccade end
                    inSaccade = false;
                    saccadeEnd = i - 1;
                    saccades = [saccades; saccadeStart, saccadeEnd];
                end
            end

            %Catch any saccade ending at the end of the data
            if inSaccade
                saccadeEnd = length(saccadeCandidates);
                saccades = [saccades; saccadeStart, saccadeEnd];
            end

            % 5. Filter using  loss metric and duration/amplitude
            [saccades , RMSdist] = filterSaccades(app,eyePositions, saccades,...
                samplingRate, params.minDuration, params.minAmplitude);
            
        end
        
        function saccadeMetrics = computeSaccadeMetrics(app,eyePositions, saccades, samplingRate)
            %COMPUTESACCADEMETRICS Computes various metrics for detected saccades.
            %
            %   Inputs:
            %       eyePositions: 2D array (n x 2) of eye positions (x, y).
            %       saccades: A matrix where each row is a saccade, with columns:
            %              start index, end index.
            %       samplingRate: Sampling rate of the data (Hz).
            %
            %   Outputs:
            %       saccadeMetrics: A struct where each field is a metric, as
            %         follows:
            %              meanVelocity:  Mean velocity for each saccade (pixels/sec).
            %              peakVelocity:  Peak velocity for each saccade (pixels/sec).
            %              direction:  Direction of each saccade (degrees).
            %              amplitude:  Amplitude of each saccade (pixels).
            %
            numSaccades = size(saccades, 1);
            saccadeMetrics.meanVelocity = zeros(numSaccades, 1);
            saccadeMetrics.peakVelocity = zeros(numSaccades, 1);
            saccadeMetrics.direction = zeros(numSaccades, 1);
            saccadeMetrics.amplitude = zeros(numSaccades, 1);

            for i = 1:numSaccades
                startIndex = saccades(i, 1);
                endIndex = saccades(i, 2);

                % Basic error handling
                if startIndex < 1 || endIndex > size(eyePositions, 1) || startIndex >= endIndex
                    fprintf('Saccade %d has invalid start or end indices.\n', i);
                    continue; % Skip to the next saccade
                end


                saccadePositions = eyePositions(startIndex:endIndex, :);

                % 1. Calculate Velocities
                % [avgVelocity, peakVelocity] = calculateVelocity2(saccadePositions);
                velocities = calculateVelocity(app,saccadePositions, samplingRate);
                speeds = sqrt(sum(velocities.^2, 2)); % Magnitude of the velocities

                % NEED to determine scaling factor -- default now to 10.
                speeds = round(speeds)/10;

                % 2. Mean Velocity
                saccadeMetrics.meanVelocity(i) = mean(speeds);

                % 3. Peak Velocity
                saccadeMetrics.peakVelocity(i) = max(speeds);

                % 4. Amplitude (Euclidian distance between start and end)
                amplitude = sqrt(sum((saccadePositions(end,:) - saccadePositions(1,:)).^2));
                saccadeMetrics.amplitude(i) = amplitude;

                % 5. Direction (using arctan2)
                deltaPos = saccadePositions(end,:) - saccadePositions(1,:); % x,y change in positions
                saccadeMetrics.direction(i) = rad2deg(atan2(deltaPos(2),deltaPos(1))); %Angle in degrees

                % 6. duration
                saccadeMetrics.duration(i) = height(saccadePositions);
            end

        end
        
        function velocities = calculateVelocity(~,positions, samplingRate)
            % Calculates the velocities from position data.
            % Assumes positions are in the format [x_pos, y_pos]
            % Returns velocity at each timepoint (except for the first one).
            % The final velocity will be the same as the penultimate velocity.
            %
            diffs = diff(positions);
            velocities = diffs * samplingRate;
            % Velocity at the last position will be the same as the velocity for
            % the penultimate position
            velocities = [velocities; velocities(end,:)];

        end
        
        function [filteredSaccades,RMSdist] = filterSaccades(app,eyePositions, saccades, samplingRate, minDuration, minAmplitude)
            % Filters saccade candidates based on minimum duration, amplitude and data spread.
            filteredSaccades = [];
            RMSdist = [];
            for i = 1:size(saccades, 1)
                startIndex = saccades(i, 1);
                endIndex = saccades(i, 2);

                % Basic error handling
                if startIndex < 1 || endIndex > size(eyePositions, 1) || startIndex >= endIndex
                    fprintf('Saccade candidate %d has invalid start or end indices.\n', i);
                    continue; % Skip to the next saccade
                end
                saccadePositions = eyePositions(startIndex:endIndex, :);

                % 1. Duration Check
                durationMs = (endIndex - startIndex + 1) / samplingRate * 1000;
                if durationMs < minDuration
                    fprintf('Saccade candidate %d failed duration test. Duration = %.2f ms.\n', i, durationMs);
                    continue; % Skip to the next check
                end

                % 2. Amplitude Check
                amplitude = sqrt(sum((saccadePositions(end,:) - saccadePositions(1,:)).^2)); % Euclidian distance between start and end
                if amplitude < minAmplitude
                    fprintf('Saccade candidate %d failed amplitude test. Amplitude = %.2f pixels.\n', i, amplitude);
                    continue; % Skip to the next check
                end

                % 3. Data Spread Check
                % If saccade contains data loss (any point is flagged), discard
                distances = [];
                %Calculate Euclidean distance between each pair of points
                numPoints = size(saccadePositions,1);
                for ii = 1:numPoints
                    if ii == numPoints
                        continue
                    else
                        dist = sqrt(sum((saccadePositions(ii,:) - saccadePositions(ii+1,:)).^2));
                        distances = [distances; dist];
                    end
                end

                %Compute the RMS value
                rmsDistance = sqrt(mean(distances.^2));

                if rmsDistance > 45
                    fprintf('Saccade candidate %d failed rms test. RMS = %.2f pixels.\n', i, rmsDistance);
                    continue
                end

                filteredSaccades = [filteredSaccades; saccades(i, :)];
                RMSdist = [RMSdist ; rmsDistance];

            end
            
        end


        function plotLINEsaccade(app , xySwitch , saccFlag)

            for lri = 1:2
                % Plot raw Scr points

                if lri == 1
                    gazeRAW = app.LeftEyeSac.G;
                    % gazeTIME = app.LeftEyeSac.T;
                    plotID = 'LeftLine';
                else
                    gazeRAW = app.RightEyeSac.G;
                    % gazeTIME = app.RightEyeSac.T;
                    plotID = 'RightLine';
                end

                switch xySwitch
                    case 'X'
                        yDATA = gazeRAW(:,1);
                    case 'Y'
                        yDATA = gazeRAW(:,2);
                end

                plot(app.(plotID), 1:length(yDATA),yDATA,'Color',[0.5 0.5 0.5],...
                    'LineStyle','-');
                xlim(app.(plotID),[1 length(yDATA)])

                if saccFlag % plot individual saccades

                    test = 1 ;

                end
                
                % Loop through saccade table

                % PLOT LINE plot use FUNCTION with X as default


            end

            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            load("plasColm.mat","cmap2use")
            app.cmap2use = cmap2use;


        end

        % Button pushed function: FileLoad
        function FileLoadButtonPushed(app, event)


            [app.GazeFILE , app.GazeLOC] = uigetfile();

            cd(app.GazeLOC)

            load(app.GazeFILE)

            % Determine which Paradigm
            % Mem-Order, New-Old, Stroop

            tmpFieldNs = fieldnames(outInfo);

            if any(matches(tmpFieldNs,{'encoding','sceneRecog','timeDiscrim'}))
                paraTYPE = 'MEMORDER';
            else
                paraTYPE = 'NEWOLD';
            end

            % Will need to update
            switch paraTYPE
                case 'MEMORDER'

                    app.eyeDATA.L = outInfo.encoding.leftEYE.gaze_Scr;
                    app.timeDATA.L = outInfo.encoding.leftEYE.gaze_Scr_time;
                    app.eyeDATA.R = outInfo.encoding.rightEYE.gaze_Scr;
                    app.timeDATA.R = outInfo.encoding.rightEYE.gaze_Scr_time;

                case 'NEWOLD'

                case 'STROOP'


            end

            loadFIRST(app)
             
            plotSACCADE(app)

            plotLINEsaccade(app ,'X', 0)


        end

        % Button pushed function: ALGO1Button
        function ALGO1ButtonPushed(app, event)
            
            cmap2useI = app.cmap2use;

            for lri = 1:2

                if lri == 1
                    gazeRAW = app.LeftEyeSac.G;
                    gazeTIME = app.LeftEyeSac.T;
                    plotID = 'Leye';
                else
                    gazeRAW = app.RightEyeSac.G;
                    gazeTIME = app.RightEyeSac.T;
                    plotID = 'Reye';
                end

                params.velocityThreshold = 3; % multiplier relative to median velocity
                params.minDuration = 7; % Minimum duration in milliseconds
                params.minAmplitude = 0.5;  % Minimum saccade amplitude in pixels
                params.windowSize = 100;
                params.velocityThresholdMultiplier = 4;

                [saccades , rmsDATA] = detectSaccadesORG(app,gazeRAW, 1000, params);

                saccadeMetrics = computeSaccadeMetrics(app,gazeRAW, saccades, 1000);

                starttime = gazeTIME(saccades(:,1));
                endtime = gazeTIME(saccades(:,2));

                app.ALGO1tab = table(saccadeMetrics.amplitude, transpose(saccadeMetrics.duration),...
                    saccadeMetrics.meanVelocity, saccadeMetrics.peakVelocity,...
                    saccadeMetrics.direction, starttime , endtime, 'VariableNames',...
                    {'amplitude','duration','velocity','peakVelocity','direction',...
                    'starttime','endtime'});

                cmapSize = size(cmap2useI, 1);
                indices = round(linspace(1, cmapSize, height(saccades))); % Generate evenly spaced indices
                spacedColors = cmap2useI(indices, :);

                for ssi = 1:height(saccades)
                    hold(app.(plotID),"on")
                    app.ALGO1tab.gazeX{ssi} = gazeRAW(saccades(ssi,1):saccades(ssi,2),1);
                    app.ALGO1tab.gazeY{ssi} = gazeRAW(saccades(ssi,1):saccades(ssi,2),2);
                    sacS = scatter(app.(plotID),gazeRAW(saccades(ssi,1):saccades(ssi,2),1),gazeRAW(saccades(ssi,1):saccades(ssi,2),2),50,spacedColors(ssi,:));
                    sacS.LineWidth = 1;
                end % SACCADE loop

                % CALL LINE PLOT Default X values

                % CALL SACCADE PLOT function

            end


        end

        % Value changed function: SwitchButton
        function SwitchButtonValueChanged(app, event)
            value = app.SwitchButton.Value;

            switch value
                case 0
                    plotLINEsaccade(app , 'X' , 0)
                    app.XValuesLabel.Text = 'X Values';
                case 1
                    plotLINEsaccade(app , 'Y' , 0)
                    app.XValuesLabel.Text = 'Y Values';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileOptionsPanel = uipanel(app.UIFigure);
            app.FileOptionsPanel.Title = 'File Options';
            app.FileLoad = uibutton(app.FileOptionsPanel, 'push');
            app.FileLoad.ButtonPushedFcn = createCallbackFcn(app, @FileLoadButtonPushed, true);
            app.FileLoad.Icon = fullfile(pathToMLAPP, 'fileload.png');
            app.FileLoad.Text = '';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = fullfile(pathToMLAPP, 'backbutton.png');
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Icon = fullfile(pathToMLAPP, 'forbutton.png');
            app.Button_2.Text = '';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Saccade'; 'Duration'; 'StartInd'; 'EndInd'};
            app.UITable.RowName = {};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LeftTab = uitab(app.TabGroup);
            app.LeftTab.Title = 'Left';
            app.Leye = uiaxes(app.LeftTab);
            title(app.Leye, 'Trial #')
            app.Leye.XTick = [];
            app.Leye.YTick = [];
            app.RightTab = uitab(app.TabGroup);
            app.RightTab.Title = 'Right';
            app.Reye = uiaxes(app.RightTab);
            title(app.Reye, 'Trial #')
            app.Reye.XTick = [];
            app.Reye.YTick = [];
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.LeftTab_2 = uitab(app.TabGroup2);
            app.LeftTab_2.Title = 'Left';
            app.GridLayout = uigridlayout(app.LeftTab_2);
            app.LeftLine = uiaxes(app.GridLayout);
            title(app.LeftLine, 'Title #')
            zlabel(app.LeftLine, 'Z')
            app.LeftLine.XTick = [];
            app.LeftLine.YTick = [];
            app.RightTab_2 = uitab(app.TabGroup2);
            app.RightTab_2.Title = 'Right';
            app.GridLayout2 = uigridlayout(app.RightTab_2);
            app.RightLine = uiaxes(app.GridLayout2);
            title(app.RightLine, 'Title #')
            zlabel(app.RightLine, 'Z')
            app.RightLine.XTick = [];
            app.RightLine.YTick = [];
            app.TabGroup3 = uitabgroup(app.UIFigure);
            app.LeftTab_3 = uitab(app.TabGroup3);
            app.LeftTab_3.Title = 'Left';
            app.GridLayout4 = uigridlayout(app.LeftTab_3);
            app.UIAxes2 = uiaxes(app.GridLayout4);
            title(app.UIAxes2, 'Saccade #')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.RightTab_3 = uitab(app.TabGroup3);
            app.RightTab_3.Title = 'Right';
            app.UIAxes2_2 = uiaxes(app.RightTab_3);
            title(app.UIAxes2_2, 'Saccade #')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Icon = fullfile(pathToMLAPP, 'backbutton.png');
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.Icon = fullfile(pathToMLAPP, 'forbutton.png');
            app.Button_4.Text = '';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.ALGO1Button = uibutton(app.Panel, 'push');
            app.ALGO1Button.ButtonPushedFcn = createCallbackFcn(app, @ALGO1ButtonPushed, true);
            app.ALGO1Button.Text = 'ALGO 1';
            app.SwitchButton = uibutton(app.UIFigure, 'state');
            app.SwitchButton.ValueChangedFcn = createCallbackFcn(app, @SwitchButtonValueChanged, true);
            app.SwitchButton.Text = 'Switch';
            app.XValuesLabel = uilabel(app.UIFigure);
            app.XValuesLabel.Text = 'X Values';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Eyenamicfix

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end